classdef CellSorter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Menu                           matlab.ui.container.Menu
        OpenMenu                       matlab.ui.container.Menu
        LoadlabelsMenu                 matlab.ui.container.Menu
        CreateasortingfileMenu         matlab.ui.container.Menu
        SaveclassifierMenu             matlab.ui.container.Menu
        SaveMenu                       matlab.ui.container.Menu
        ExitMenu                       matlab.ui.container.Menu
        MainGridLayout                 matlab.ui.container.GridLayout
        TabGroup                       matlab.ui.container.TabGroup
        LabelingTab                    matlab.ui.container.Tab
        LabelingGridLayout             matlab.ui.container.GridLayout
        TotalProgressLabeledLabel      matlab.ui.control.Label
        TotalSummaryLabel              matlab.ui.control.Label
        DatasetSummaryLabel            matlab.ui.control.Label
        TotalUnlabeledEditField        matlab.ui.control.NumericEditField
        UnlabeledEditField_2Label      matlab.ui.control.Label
        TotalBadEditField              matlab.ui.control.NumericEditField
        BadEditField_2Label            matlab.ui.control.Label
        TotalGoodEditField             matlab.ui.control.NumericEditField
        GoodEditField_2Label           matlab.ui.control.Label
        DatasetDropDown                matlab.ui.control.DropDown
        PreviousDataset                matlab.ui.control.Button
        NextDataset                    matlab.ui.control.Button
        LabeledEditField               matlab.ui.control.NumericEditField
        LabeledEditFieldLabel          matlab.ui.control.Label
        UnlabeledEditField             matlab.ui.control.NumericEditField
        UnlabeledEditFieldLabel        matlab.ui.control.Label
        BadEditField                   matlab.ui.control.NumericEditField
        BadEditFieldLabel              matlab.ui.control.Label
        GoodEditField                  matlab.ui.control.NumericEditField
        GoodEditFieldLabel             matlab.ui.control.Label
        CellIndexEditField             matlab.ui.control.NumericEditField
        CellIndexEditFieldLabel        matlab.ui.control.Label
        CellSelectionSlider            matlab.ui.control.Slider
        NextButton                     matlab.ui.control.Button
        PreviousButton                 matlab.ui.control.Button
        CellButton                     matlab.ui.control.Button
        NotaCellButton                 matlab.ui.control.Button
        UnlabeledButton                matlab.ui.control.Button
        ToolsTab                       matlab.ui.container.Tab
        ToolsGridLayout                matlab.ui.container.GridLayout
        SnapshotContrastDropDown       matlab.ui.control.DropDown
        SnapshotContrastDropDownLabel  matlab.ui.control.Label
        ScanToSelectToggle             matlab.ui.control.StateButton
        ClickToSelectToggle            matlab.ui.control.StateButton
        HideGoodCellsCheckBox          matlab.ui.control.CheckBox
        HideGoodCellsLabel             matlab.ui.control.Label
        PlaySnapshotsLabel             matlab.ui.control.Label
        HideBadCellsLabel              matlab.ui.control.Label
        PlaybackSpeedDropDown          matlab.ui.control.DropDown
        PlaybackSpeedLabel             matlab.ui.control.Label
        CellBorderColorDropDown        matlab.ui.control.DropDown
        CellBorderColorLabel           matlab.ui.control.Label
        CellLabelTransparencySliderLabel  matlab.ui.control.Label
        CellColorTransparencySlider    matlab.ui.control.Slider
        ZoomLabel                      matlab.ui.control.Label
        ZoomSlider                     matlab.ui.control.Slider
        ZoomOutButton                  matlab.ui.control.Button
        ZoomButton                     matlab.ui.control.StateButton
        SnapshotMaxContrastSlider      matlab.ui.control.Slider
        SnapshotMinContrastSlider      matlab.ui.control.Slider
        PlaySnapshotsCheckBox          matlab.ui.control.CheckBox
        HideBadCellsCheckBox           matlab.ui.control.CheckBox
        MaxContrastSlider              matlab.ui.control.Slider
        MainMapContrastLabel           matlab.ui.control.Label
        MinContrastSlider              matlab.ui.control.Slider
        ActiveLearningTab              matlab.ui.container.Tab
        ActiveLearningGridLayout       matlab.ui.container.GridLayout
        CellThresholdClassifierEditField  matlab.ui.control.NumericEditField
        CellThresholdClassifierEditFieldLabel  matlab.ui.control.Label
        WeightEditField                matlab.ui.control.NumericEditField
        QueryMethodDropDown            matlab.ui.control.DropDown
        QueryMethodLabel               matlab.ui.control.Label
        UserLabel                      matlab.ui.control.Label
        ActSortLabel                   matlab.ui.control.Label
        OverallLabel                   matlab.ui.control.Label
        UserDecision                   matlab.ui.control.Label
        UserDecisionLabel              matlab.ui.control.Label
        ActSortDecision                matlab.ui.control.Label
        ActSortDecisionLabel           matlab.ui.control.Label
        ActSortScore                   matlab.ui.control.Label
        ActSortScoreLabel              matlab.ui.control.Label
        PredictCellsButton             matlab.ui.control.StateButton
        CellStatsAxes                  matlab.ui.control.UIAxes
        SpatialFilterAxes              matlab.ui.control.UIAxes
        SnapshotAxes4                  matlab.ui.control.UIAxes
        SnapshotAxes5                  matlab.ui.control.UIAxes
        SnapshotAxes3                  matlab.ui.control.UIAxes
        SnapshotAxes2                  matlab.ui.control.UIAxes
        SnapshotAxes1                  matlab.ui.control.UIAxes
        TraceAxes                      matlab.ui.control.UIAxes
        CellMapAxes                    matlab.ui.control.UIAxes
    end

    properties (Access = private)
        % APP VARIABLES
        session                                                      
        selectedCells                single 
        curFrameNum                  single
        selectedFrames               single                
        Timer                        timer
        tButtonPressed               logical = false;
        axesList
        useAutoContrast              logical = true;

        % MOUSE MOVEMENT ITEMS
        startPoint = [];
        currentPoint = [];
        endPoint = [];
        rectangleHandle = [];
        rectPos = []; %(1)x (2)y (3)width (4)height (Origin is atleft top corner)

        % OPEN MENU ITEMS
        dataset_pretrained                   = []; 
        precomputedFilePaths         cell    = {};
        balancePretrained            logical = false;
        
        % ACTIVE LEARNING
        dataset
        config
        method

        % INFO
        LABELING_TIMER = 0;
    end
    
    methods (Access = private)
        
        function idx = getCurrentDataset(app)
            idx = app.DatasetDropDown.Value;
            idx = str2double(idx);
        end
        
        function [dset, idx] = getLastSelectedCell(app)
            if isempty(app.selectedCells)
                dset = 1;
                idx = 1;
                return;
            end
            dset = app.selectedCells(1, 1);
            idx = app.selectedCells(1, 2);
        end
        
        function [dset_idx, cell_idx] = nextCellNoAL(app)
            [current_dset, current_cell] = app.getLastSelectedCell();
            numCells = app.session.numCells{current_dset};
            if current_cell + 1 > numCells
                dset_idx = min(current_dset + 1, app.session.numDatasets);  % Moved to next dataset if possible
                cell_idx = 1;
            else
                dset_idx = current_dset;
                cell_idx = current_cell + 1;
            end
        end

        function [dset_idx, cell_idx] = setNextCell(app)
            % Set a new cell to sort using AL
            if app.QueryMethodDropDown.Value == "none"
                [dset_idx, cell_idx] = nextCellNoAL(app);
                return;
            end
        
            sorting_order = app.dataset.sorting_order;
            single_cell_selected = (size(app.selectedCells, 1) == 1);
        
            % Check if the selected cell is already in the sorted list
            if isempty(sorting_order)
                cell_already_sorted = false;
            else
                [current_dset, current_cell] = app.getLastSelectedCell();
                cell_already_sorted = any(ismember([current_dset, current_cell], sorting_order, 'rows'));
            end
        
            % Check if it's not the last sorted cell
            not_last_sorted_cell = isempty(sorting_order) || ...
                ~isequal(app.selectedCells(1, :), sorting_order(end, :));
        
            if single_cell_selected && cell_already_sorted && not_last_sorted_cell
                [dset_idx, cell_idx] = go_next_sorted_cell(sorting_order, app.selectedCells(1, :));
            else
                [cell, ~, app.dataset] = step_al(app.dataset, app.method);
                
                % If all the cells are sorted, go to next cell
                if isnan(cell)
                    [dset_idx, cell_idx] = nextCellNoAL(app);
                else
                    dset_idx = cell(1);
                    cell_idx = cell(2);
                end
            end
        end


        function [dset, idx] = setPreviousCell(app)
            [current_dset, current_idx] = app.getLastSelectedCell(); % Unpack the dataset and cell index

            if app.QueryMethodDropDown.Value == "none"
                sorting_order = [];
            else
                sorting_order = app.dataset.sorting_order;
            end
            
            if isempty(sorting_order)
                idx = current_idx - 1;
                if idx == 0
                    dset = max(1, current_dset - 1);
                    idx = app.session.numCells{dset};
                else
                    dset = current_dset;
                end
            else    
                [rowExists, rowIdx] = ismember([current_dset, current_idx], sorting_order, 'rows');

                if rowExists
                    previousIdx = max(rowIdx - 1, 1);
                    idx = sorting_order(previousIdx, 2);   % Get cell number
                    dset = sorting_order(previousIdx, 1);  % Get dataset index
                else
                    dset = sorting_order(end, 1);
                    idx = sorting_order(end, 2);
                end
            end
        
        end
        
        function LabelCells(app, label)
        % Labels the selected cells with a given label
            % Update the timer
            app.session = app.session.update_total_time_sorted(app.LABELING_TIMER);
            
            % Annotate cells
            selected_cells = app.selectedCells;
            predict_cells  = app.PredictCellsButton.Value; 

            app.dataset = annotate(app.dataset, selected_cells, label);
            app.session = app.session.update_labels(app.dataset, predict_cells);

            % Increase number of cells sorted (for INFO)
            num_cells_sorted = size(selected_cells, 1);
            app.session = app.session.increase_num_labeling(num_cells_sorted);

            % Train the model
            [app.dataset, app.dataset_pretrained, app.method] = update_model(app.dataset, app.dataset_pretrained, app.method);
            app.resetLabelingTimer();    
        end

        function labels = createLabelsFile(app, dset)
            labels = struct();
            labels.labels_ex = app.dataset.labels_ex{dset};
            labels.labels_ml = app.dataset.labels_ml{dset};
            labels_overall = predict_rest(app.dataset);
            labels.labels_overall = labels_overall{dset};
        end

        %~-~-~-~-~-~-~-~-~-~- INPUT HANDLING ~-~-~-~-~-~-~-~-~-~-%

        % Parses input data and initializes the application state
        function ParseInput(app, precomputedOutputs)
            app.session = session(precomputedOutputs);

            % Assign precomputed file name
            filenames = cellfun(@(path) strcat(getFileName(app, path)), app.precomputedFilePaths, 'UniformOutput', false);
            app.session.precomputed_file_names = filenames;  % Now a cell array of names
            
            % Retrieve configurations 
            cls_threshold = app.CellThresholdClassifierEditField.Value;
            method_name = app.QueryMethodDropDown.Value;
            weight = app.WeightEditField.Value;
            continue_sorting = ~isempty(app.dataset_pretrained);
            balancePretrained = app.balancePretrained;

            % Initiate config
            app.config = config('method_name', method_name, ...
                'weight', weight, ...
                'cls_threshold', cls_threshold, ...
                'continue_sorting', continue_sorting, ...
                'balance_pretrained', balancePretrained ...
                );
            
            % Initiate dataset and method
            features = create_features_cell(app, precomputedOutputs);
            [app.dataset, app.method] = initialization(features, app.config, app.dataset_pretrained);
            app.Timer = timer('ExecutionMode', 'fixedRate', ...
                'Period', str2double(app.PlaybackSpeedDropDown.Value), ...
                'TimerFcn', @(~,~) UpdateFrame(app));
        end

        function name = getFileName(~, pathStr)
            [~, name, ext] = fileparts(pathStr);
            name = [name ext];
        end


        function features = create_features_cell(~, precomputedOutputs)
            numOutputs = numel(precomputedOutputs);
            features = cell(1, numOutputs);
        
            for i = 1:numOutputs
                features{i} = precomputedOutputs{i}.features;
            end
        end

        %~-~-~-~-~-~-~-~-~-~- TRACE AXES HANDLING ~-~-~-~-~-~-~-~-~-~-%

        % Plots the trace of the currently selected cell
        function PlotTraces(app)
            [dset_idx, cell_idx] = app.getLastSelectedCell();
            trace = app.session.traces{dset_idx}(:, cell_idx);
            spike_points = app.session.spikeIdxs{dset_idx}(:, cell_idx);
            ax = app.TraceAxes;

            plot(ax, trace, 'color', 'k');
            set_trace_axis_lims(ax, trace);
            plot_spike_nums(ax, spike_points, trace)
        end

        %~-~-~-~-~-~-~-~-~-~- SNAPSHOT HANDLING ~-~-~-~-~-~-~-~-~-~-%

        % Plots snapshots for the selected cell
        function PlotSnapshots(app)
            
            [currentDataset, currentCell] = app.getLastSelectedCell();
            numSpikes = app.session.get_num_spikes(currentDataset, currentCell);
            if numSpikes == 0
                return;
            end

            cellBoundaries = app.session.snapshotCellBoundaries{currentDataset}{currentCell};
            neighborBoundaries = app.session.neighborBoundaries{currentDataset}{currentCell};
            displayRange = [app.SnapshotMinContrastSlider.Value, app.SnapshotMaxContrastSlider.Value];
            
            clear_axes(app.axesList);
            for idx = 1:numSpikes
                ax = app.axesList(idx);
                currentSnapshots = app.session.snapshots{currentDataset}{currentCell}(:,:,:,idx);

                % Display the summary snapshots
                summarySnapshots = generate_summary_snapshots(currentSnapshots);
                imagesc(ax, summarySnapshots, displayRange);
                
                % Draw the boundaries
                COLOR = app.CellBorderColorDropDown.Value;
                draw_snapshot_boundaries(ax, cellBoundaries)
                draw_snapshot_neighbor_boundaries(ax, neighborBoundaries, COLOR);
            end

            if app.PlaySnapshotsCheckBox.Value
                updateSnapshots(app)
            end
        end

        function PlotZoomedTraces(app)
            if app.PlaySnapshotsCheckBox.Value
                stopSnapshots(app);
            end
            clear_axes(app.axesList);
            convertSnapshotAxisStyle(app)
            displayZoomedTraces(app);
        end

        function convertSnapshotAxisStyle(app)
            [currentDataset, currentCell] = app.getLastSelectedCell();
            numSpikes = app.session.get_num_spikes(currentDataset, currentCell);
            if app.tButtonPressed
                for i = 1:numSpikes
                    ax = app.axesList(i);
                    axis(ax, 'normal');
                    set(ax, 'YDir', 'normal');
                    ax.XColor = [0 0 0];
                    ax.YColor = [0 0 0];
                end
            else
                for i = 1:numSpikes
                    ax = app.axesList(i);
                    axis(ax,'image');
                    ax.XColor = 'none';
                    ax.YColor = 'none';
                end
            end 
        end

        function displayZoomedTraces(app)
            Y_LIM_MARGIN = 1.25; % how much margin to have from upside of axis
            FRAME_MARGIN = 0.0035;

            [dsetIdx, cellIdx] = app.getLastSelectedCell();
            trace = app.session.traces{dsetIdx}(:, cellIdx);
            zoomInterval = round(size(trace,1)*FRAME_MARGIN); % amount to zoom +- frames
            spikes = app.session.spikeIdxs{dsetIdx}(:, cellIdx);

            % Cut the specific zoomed trace part
            [zoomedTraces, zoomedSpikeIdxs] = find_zoomed_traces(trace, spikes, zoomInterval);
            numSpikes = app.session.get_num_spikes(dsetIdx, cellIdx);
            for i = 1:numSpikes
                ax = app.axesList(i);
                trace_to_plot = zoomedTraces{i};

                % Set plot X and Y limits
                xLim = [1, size(trace_to_plot, 1)];
                yLim = [min(trace_to_plot), max(trace_to_plot)*(Y_LIM_MARGIN)];
                plot(ax, trace_to_plot, 'color', 'k');
                set(ax, 'ylim', yLim,'xlim', xLim);

                % Plot Spike Number
                xPoint = zoomedSpikeIdxs(i);
                text(xPoint, trace_to_plot(xPoint), string(i), 'Parent', ax, ...
                'Fontweight', 'bold', 'color', [0.3, 0.5, 1]);
            end
        end

        % Updates the frame in the snapshot playback
        function UpdateFrame(app)  
            if app.curFrameNum < size(app.selectedFrames,3)
                app.curFrameNum = app.curFrameNum + 1;
            else
                app.curFrameNum = 1;
            end
    
            [currentDset, currentCell] = app.getLastSelectedCell();
            numSpikes = app.session.get_num_spikes(currentDset, currentCell);
            for spikeIdx = 1:numSpikes
                ax = app.axesList(spikeIdx);
                snapshotHandle = findobj(ax, 'Type', 'image');
                currentSnapshot = app.selectedFrames(:, :, app.curFrameNum, spikeIdx);
                set(snapshotHandle,'CData', currentSnapshot)
                displayRange = [app.SnapshotMinContrastSlider.Value app.SnapshotMaxContrastSlider.Value];
                set(ax,'CLim', displayRange)
            end
            
            drawnow limitrate;
        end

        % Starts snapshot playback
        function playSnapshots(app)
            updateSnapshots(app);
            start(app.Timer)
            app.PlaybackSpeedDropDown.Enable = 'on';
            app.PlaybackSpeedLabel.Enable = 'on';
        end

        % Stops snapshot playback
        function stopSnapshots(app)
            app.PlaybackSpeedDropDown.Enable = 'off';
            app.PlaybackSpeedLabel.Enable = 'off';
            stop(app.Timer)
            drawnow;
            PlotSnapshots(app)
        end

        function updateSnapshots(app)
            [currentDset, currentIdx] = app.getLastSelectedCell();
            app.selectedFrames = app.session.snapshots{currentDset}{currentIdx};
            app.curFrameNum = 1;
        end
    
        %~-~-~-~-~-~-~-~-~-~- CLICK CALL BACK ~-~-~-~-~-~-~-~-~-~-%

        % Handles clicks on the cell map image
        function ImageClickCallback(app, event)
        % Check if the function should return early based on button states
            if app.ZoomButton.Value || app.ScanToSelectToggle.Value
                return;
            end
            % Calculate adjusted coordinates from the axes point
            currentDset = app.getCurrentDataset();
            H = app.session.H{currentDset}; W = app.session.W{currentDset};
            [adjustedX, adjustedY] = get_adjusted_coordinates(event, W, H);
            
            % Find the indices of present cells
            presentCellsIdx = getPresentCellsIndices(app, event, adjustedX, adjustedY);
        
            % Process cell selection logic
            processCellSelection(app, presentCellsIdx, adjustedX, adjustedY);
        end
        
        % Finds indices of cells present at the clicked location
        function presentCellsIdx = getPresentCellsIndices(app, event, adjustedX, adjustedY)
            ax = event.Source.Parent;
            currentDset = app.getCurrentDataset();
            H = app.session.H{currentDset}; W = app.session.W{currentDset};
            xImage = round(axes2pix(W, get(ax, 'XLim'), adjustedX));
            yImage = round(axes2pix(H, get(ax, 'YLim'), adjustedY));

            % Apply logical indexing
            logical = app.session.spatial_weights{currentDset}(yImage, xImage, :);
            presentCellsIdx = find(logical);
            presentCellsIdx = app.filterCells(presentCellsIdx);
        end
        
        % Filters present cell indices based on visibility settings
        function cells_idxs = filterCells(app, cells_idxs)
            [currentDset, currentCell] = app.getLastSelectedCell();
            % Filter out currently selected cell if not multiple selecting
            if ~app.ClickToSelectToggle.Value
                cells_idxs = cells_idxs(cells_idxs ~= currentCell);
            end
            
            % Filter out good cells if hidden
            if app.HideBadCellsCheckBox.Value
                cells_idxs = cells_idxs(app.session.cellLabels{currentDset}(cells_idxs) ~= -1);
            end
            
            % Filter out good bad if hidden
            if app.HideGoodCellsCheckBox.Value
                cells_idxs = cells_idxs(app.session.cellLabels{currentDset}(cells_idxs) ~= 1);
            end
        end
        
        % Processes cell selection logic based on present cells and click coordinates
        function processCellSelection(app, presentCellsIdx, adjustedX, adjustedY)
            if isempty(presentCellsIdx)
                return;
            end
            currentDset = app.getCurrentDataset();
            cellCenters = app.session.cellCenters{currentDset}(:, presentCellsIdx);
            dists = vecnorm(double(abs(cellCenters) - [adjustedY; adjustedX]), 2, 1);
            [~, idx] = min(dists);
            closestCellIdx = presentCellsIdx(idx);
            updateSelectedCells(app, [currentDset, closestCellIdx]);
        end

        % Updates the list of selected cells
        function updateSelectedCells(app, selectedIdx)
            if app.ClickToSelectToggle.Value
                handleMultipleSelect(app, selectedIdx);
            else
                app.selectedCells = selectedIdx;
            end
            UpdateUI(app);
            if app.tButtonPressed
                PlotZoomedTraces(app);
            else
                PlotSnapshots(app);
            end
            PlotSnapshotFilters(app);
            PlotTraces(app);
            dset = app.getCurrentDataset;
            app.UpdateCellMap(dset);
            refreshZoom(app);
        end
        
        % Toggles the selection state of a cell for multiple selection mode.
        function handleMultipleSelect(app, selectedIdx)
            % Check if selectedIdx already exists in app.selectedCells
            isSelected = ismember(selectedIdx, app.selectedCells, 'rows');
        
            if ~any(isSelected)
                app.selectedCells = [app.selectedCells; selectedIdx];
            else
                app.selectedCells(isSelected, :) = [];
            end
        end
        
        % Removes a cell from the selection list if multiple cells are selected.
        function removeSelectedCell(app, selectedIdx)
            if size(app.selectedCells, 1) <= 1
                return; % Only one or no cell selected, nothing to remove
            end
        
            % Remove the matching row (both dataset and cell index)
            isMatch = ismember(selectedIdx, app.selectedCells, 'rows');
            app.selectedCells(isMatch, :) = [];
        end
        
        %~-~-~-~-~-~-~-~-~-~- ZOOM HANDLING ~-~-~-~-~-~-~-~-~-~-%

        % Refresh zoom when changing cells
        function refreshZoom(app)
            ZoomSliderValueChanged(app);
        end
        
        % Handles zoom area selection
        function handleZoomButton(app, finalRectPos)
            currentDset = app.getCurrentDataset();
            H = app.session.H{currentDset}; W = app.session.W{currentDset};
            newXLim = max(min([finalRectPos(1), finalRectPos(1)+finalRectPos(3)], W), 1);
            newYLim = max(min([finalRectPos(2), finalRectPos(2)+finalRectPos(4)], H), 1);
            width = newXLim(2) - newXLim(1);
            height = newYLim(2) - newYLim(1);
            zoomRange = app.ZoomSlider.Limits(2) - app.ZoomSlider.Limits(1);
            zoomLevel = ((width*height)/(H*W))*(zoomRange);
            zoomLevel = app.ZoomSlider.Limits(2) - zoomLevel; % To ensure slider consistency
            updateZoomLimits(app, newXLim, newYLim, zoomLevel);
        end
        
        % Updates the zoom level and limits of the cell map
        function updateZoomLimits(app, newXLim, newYLim, zoomLevel)
            app.CellMapAxes.XLim = newXLim;
            app.CellMapAxes.YLim = newYLim;
            app.ZoomSlider.Value = double(zoomLevel);
            app.ZoomOutButton.Enable = (zoomLevel ~= app.ZoomSlider.Limits(1));
        end

        %~-~-~-~-~-~-~-~-~-~- MAIN MAP HANDLING ~-~-~-~-~-~-~-~-~-~-%
        
        % Creates an image object for the main map display
        function CreateMainMapImage(app, dset)
            H = app.session.H{dset}; W = app.session.W{dset};
            app.CellMapAxes.XLim = [0 W];
            app.CellMapAxes.YLim = [0 H];
            mainMapHandle = imagesc(app.CellMapAxes, app.session.maxProj{dset});
            set(mainMapHandle, 'Tag', 'MainMap');
        end
        
        % Updates the contrast of the main map display
        function UpdateMainMapContrast(app)
            displayRange = [app.MinContrastSlider.Value, app.MaxContrastSlider.Value];
            set(app.CellMapAxes, 'CLim', displayRange);
        end
        
        % Updates the display of cell boundaries on the map
        function UpdateCellBorders(app, dset)
            % Clear all the borders
            patch_handle = findobj(app.CellMapAxes, 'Type', 'patch', 'Tag', 'Patch');
            delete(patch_handle);

            COLOR = app.CellBorderColorDropDown.Value;        
            if COLOR == "noborder"
                return;
            end

            % Plot the new boundaries
            [boundaryX, boundaryY] = setNewBoundaries(app, dset);
            patch(app.CellMapAxes, boundaryX, boundaryY, 'k', ...
                  'EdgeAlpha', 1, 'LineWidth', 0.1, ...
                  'EdgeColor', COLOR, 'Tag', 'Patch');
        end

        % Updates the cell boundaries displayed on the map
        function [boundaryX, boundaryY] = setNewBoundaries(app, dset)
            labels = app.session.cellLabels{dset};
            if app.HideBadCellsCheckBox.Value && app.HideGoodCellsCheckBox.Value
                cell_indices = find(labels == 0);
            elseif app.HideBadCellsCheckBox.Value
                cell_indices = find(labels ~= -1);
            elseif app.HideGoodCellsCheckBox.Value
                cell_indices = find(labels ~= 1);
            else
                cell_indices = 1:app.session.numCells{dset}; % Show all cells
            end
            [boundaryX, boundaryY] = app.session.get_boundaries(cell_indices, dset);
        end

        %~-~-~-~-~-~-~-~-~-~- CELL MAP HANDLING ~-~-~-~-~-~-~-~-~-~-%
        
        % Updates the cell map display with color-coded layers
        function UpdateCellMap(app, dset)
            greens = app.generateColorLayer(dset, "green");
            reds   = app.generateColorLayer(dset, "red");
            blues  = app.generateColorLayer(dset, "blue");
            
            RGB(:,:,1) = full(reds);
            RGB(:,:,2) = full(greens);
            RGB(:,:,3) = full(blues);

            alphaMask = create_alpha_mask(RGB, app.CellColorTransparencySlider.Value);
            app.plotRGB(RGB, alphaMask);

            app.UpdateCellBorders(dset);
        end
        
        % Plots the RGB cell map with an alpha mask
        function plotRGB(app, rgb, alphamask)
            delete(findobj(app.CellMapAxes, 'Type', 'image', 'Tag', 'RGB'));
            im = imshow(rgb, 'Parent', app.CellMapAxes,'InitialMagnification','fit');
            set(im, 'Tag', 'RGB', 'AlphaData', alphamask, ...
            'ButtonDownFcn', @(src, event)ImageClickCallback(app, event));
        end
        
        % Generates a color layer for the cell map
        function layer = generateColorLayer(app, dset, color)
            spatial_weights = app.session.spatial_weights{dset};
            cell_labels     = app.session.cellLabels{dset};
            switch color
                case "green"
                    cond      = (~app.HideGoodCellsCheckBox.Value);
                    goodCells = (cell_labels == 1);
                    layer     = sum(spatial_weights(:,:,goodCells),3)*cond;
                case "red"
                    cond      = (~app.HideBadCellsCheckBox.Value);
                    badCells  = (cell_labels == -1);
                    layer     = sum(spatial_weights(:,:, badCells),3)*cond;
                case "blue"
                    mask = app.selectedCells(:, 1) == dset;
                    cellIndices = app.selectedCells(mask, 2);
                    layer     = sum(spatial_weights(:,:,cellIndices),3);
            end
        end
        
        %~-~-~-~-~-~-~-~-~-~- UI HANDLING ~-~-~-~-~-~-~-~-~-~-%
        
        function UpdateUI(app)
            [~, cellIdx] = app.getLastSelectedCell();
            app.CellIndexEditField.Value  = double(cellIdx);
            app.CellSelectionSlider.Value = double(cellIdx);
            
            paintUI(app, cellIdx);
            updateStats(app);
            if app.useAutoContrast
                updateContrastSliderVals(app);
            end
        end

        % Plots spatial filters for the selected cell
        function PlotSnapshotFilters(app)
            [currentDset, currentCell] = app.getLastSelectedCell();
            ax = app.SpatialFilterAxes;
            
            cellBoundary = app.session.snapshotCellBoundaries{currentDset}{currentCell};
            imshow(full(app.session.snapshot_filters{currentDset}{currentCell}), 'parent', ax);
            patch(ax, cellBoundary(1,:), cellBoundary(2,:), 'k', 'LineWidth', 2,'FaceColor', 'none','EdgeColor', [0.3, 0.5, 1]);
        end

        % Updates the UI colors based on the selected cell's label
        function paintUI(app, cellIdx)
            dset = getCurrentDataset(app);
            GREEN = [0.9 1 0.9];
            RED = [1 0.9 0.9];
            GRAY = [0.94 0.94 0.94];
            switch app.session.cellLabels{dset}(cellIdx)
                case 1
                    app.LabelingGridLayout.BackgroundColor = GREEN;
                    app.ToolsGridLayout.BackgroundColor = GREEN;
                    app.ActiveLearningGridLayout.BackgroundColor = GREEN;
                case -1
                    app.LabelingGridLayout.BackgroundColor = RED;
                    app.ToolsGridLayout.BackgroundColor = RED;
                    app.ActiveLearningGridLayout.BackgroundColor = RED;
                case 0 
                    app.LabelingGridLayout.BackgroundColor = GRAY;
                    app.ToolsGridLayout.BackgroundColor = GRAY;
                    app.ActiveLearningGridLayout.BackgroundColor = GRAY;
            end
        end
        
        % Updates the values of contrast sliders for snapshots
        function updateContrastSliderVals(app)
            [dset, idx] = getLastSelectedCell(app);
            CLims = double(app.session.snapshotCLims{dset}(:, idx));
            app.SnapshotMinContrastSlider.Limits = CLims(1:2);
            app.SnapshotMaxContrastSlider.Limits = CLims(1:2);
            app.SnapshotMinContrastSlider.Value = CLims(3);
            app.SnapshotMaxContrastSlider.Value = CLims(4);
        end
    
        % Updates statistics displayed in the UI
        function updateStats(app)
            % Update Cell Count Stats
            updateLabeledCellCounts(app);

            % Update ActSort Score
            updateActSortScore(app);

            % Update ActSort Decision
            updateActsortDecision(app);

            % Update User Decision
            updateUserDecision(app);

            % Update Stat Bars
            updateStatBars(app);
        end    

        function updateUserDecision(app)
            [dset, idx] = getLastSelectedCell(app);
            if app.dataset.labels_ex{dset}(idx) == 1
                app.UserDecision.Text = "Cell";
            elseif app.dataset.labels_ex{dset}(idx) == -1
                app.UserDecision.Text = "Not a Cell";
            else
                app.UserDecision.Text = "Unlabeled";
            end
        end

        function updateActsortDecision(app)
            [dset, idx] = getLastSelectedCell(app);
            if app.dataset.labels_ml{dset}(idx) == 1
                app.ActSortDecision.Text = "Cell";
            elseif app.dataset.labels_ml{dset}(idx) == -1
                app.ActSortDecision.Text = "Not a Cell";
            else
                app.ActSortDecision.Text = "Unlabeled";
            end
        end

        function updateLabeledCellCounts(app)
            curDset = app.getCurrentDataset();
            stats = app.dataset.get_expert_stats{curDset};
            [numGood, numBad, numUnlabeled] = deal(stats(1), stats(2), stats(3));
            app.GoodEditField.Value = double(numGood);
            app.BadEditField.Value = double(numBad);
            app.UnlabeledEditField.Value = double(numUnlabeled);
            app.LabeledEditField.Value = double(numGood + numBad);

            numDatasets = numel(app.dataset.get_expert_stats);
            total_good = 0;
            total_bad = 0;
            total_unlabeled = 0;

            for i = 1:numDatasets
                stats_i = app.dataset.get_expert_stats{i};
                total_good = total_good + stats_i(1);
                total_bad = total_bad + stats_i(2);
                total_unlabeled = total_unlabeled + stats_i(3);
            end

            total_labeled = total_good + total_bad;
            total_cells = total_good + total_bad + total_unlabeled;
            
            % Update total numeric fields
            app.TotalGoodEditField.Value = double(total_good);
            app.TotalBadEditField.Value = double(total_bad);
            app.TotalUnlabeledEditField.Value = double(total_unlabeled);
            
            if total_cells > 0
                labeled_percent = (total_labeled / total_cells) * 100;
            else
                labeled_percent = 0;
            end

            app.TotalProgressLabeledLabel.Text = ...
                sprintf('Total Labeled: %d / %d (%.1f%%)', ...
                total_labeled, total_cells, labeled_percent);            
        end

        function updateStatBars(app)
            colors = [0.47,0.67,0.19; 0.98,1.00,0.98; 1.00,0.25,0.25]; % Green, Gray, Red
            ax = app.CellStatsAxes;
            
            % Initiate the data matrix
            dset = app.getCurrentDataset();
            overall_data = app.dataset.get_overall_stats{dset};
            actsort_data = app.dataset.get_model_stats{dset};
            expert_data = app.dataset.get_expert_stats{dset};

            % Clear axes and plot each bar 
            clear_axes(ax);
            plot_bar_rectangle(ax, overall_data(:, [1 3 2]), 2, colors)
            plot_bar_rectangle(ax, actsort_data(:, [1 3 2]), 1, colors)
            plot_bar_rectangle(ax, expert_data(:, [1 3 2]), 0, colors)
        end

        function updateActSortScore(app)
            [dset, idx] = getLastSelectedCell(app);
            probGood = app.dataset.labels_ml_prob{dset}(idx);
            roundedDownValue = floor(probGood * 100) / 100;
            formattedValue = sprintf('%.2f', roundedDownValue);
            app.ActSortScore.Text = num2str(formattedValue);
        end

        % Initializes the state of various UI elements
        function InitiateUIState(app)
            objectsToEnable = [app.CellSelectionSlider, app.UnlabeledButton, app.NotaCellButton, ...
                app.CellButton, app.CellIndexEditField, app.CellIndexEditFieldLabel, ...
                app.NextButton, app.PreviousButton, app.CellSelectionSlider, app.UnlabeledButton, ...
                app.NotaCellButton, app.CellButton, app.CellIndexEditField, app.NextButton, ...
                app.CellIndexEditFieldLabel, app.PreviousButton, app.SaveMenu, ...
                app.ZoomLabel, app.ZoomSlider, app.HideBadCellsCheckBox, app.MaxContrastSlider, ...
                app.MinContrastSlider, app.MainMapContrastLabel, app.SnapshotMaxContrastSlider, ...
                app.SnapshotMinContrastSlider, app.SnapshotContrastDropDown, app.ClickToSelectToggle, ...
                app.LoadlabelsMenu, app.PlaySnapshotsCheckBox, app.ZoomButton, app.ScanToSelectToggle,...
                app.CellColorTransparencySlider, app.CellLabelTransparencySliderLabel, app.CellBorderColorDropDown, ...
                app.CellBorderColorLabel, app.PlaybackSpeedDropDown, app.PlaybackSpeedLabel, app.GoodEditField, ...
                app.GoodEditFieldLabel, app.BadEditField, app.BadEditFieldLabel,app.LabeledEditFieldLabel, ...
                app.TotalBadEditField, app.TotalGoodEditField, app.TotalProgressLabeledLabel, ...
                app.TotalUnlabeledEditField, ...
                app.PlaySnapshotsLabel, app.HideBadCellsLabel, app.UserLabel, app.LabeledEditField, ...
                app.SaveclassifierMenu, app.WeightEditField, app.ActSortScore, app.CellThresholdClassifierEditFieldLabel, ...
                app.ActSortScoreLabel, app.ActSortLabel, app.OverallLabel,app.UnlabeledEditField, ...
                app.HideGoodCellsLabel, app.HideGoodCellsCheckBox, app.UserDecision, app.UserDecisionLabel, ...
                app.ActSortDecision, app.ActSortDecisionLabel, app.QueryMethodDropDown, app.QueryMethodLabel, ...
                app.PredictCellsButton, app.CellThresholdClassifierEditField, app.UnlabeledEditFieldLabel, ...
                ];
            
            update_enable_state(objectsToEnable,'on');

            if app.session.numDatasets > 1
                app.NextDataset.Enable = 'on';
                app.PreviousDataset.Enable = 'on';
                app.DatasetDropDown.Enable = 'on';
                app.DatasetDropDown.Items = cellstr(string(1:app.session.numDatasets));
            end

            app.ZoomLabel.Visible = 'on';
            app.TraceAxes.Visible = 'on';
            app.CellMapAxes.Visible = 'on';
            app.SaveMenu.Accelerator = 'S';
            app.OpenMenu.Enable = 'off';

            % Set cell selection slider and edit field
            curDset = app.getCurrentDataset();
            numCells = double(app.session.numCells{curDset});
            app.CellIndexEditField.Limits = [1 numCells];
            app.CellSelectionSlider.Limits = [1 numCells];
            
            % Set contrast slider limits and values
            curDset = app.getCurrentDataset();
            maxProj = double(app.session.maxProj{curDset}(:));
            minVal = min(maxProj);
            maxVal = max(maxProj);
            app.MinContrastSlider.Limits = [minVal maxVal];
            app.MaxContrastSlider.Limits = [minVal maxVal];
            app.MinContrastSlider.Value = minVal;
            app.MaxContrastSlider.Value = maxVal;

            app.CellStatsAxes.XLim = [0 numCells];
            app.CellStatsAxes.YLim = [0 3];

            app.CellMapAxes.XColor = 'none';
            app.CellMapAxes.YColor = 'none';

            app.TraceAxes.XColor = [0 0 0];
            app.TraceAxes.YColor = [0 0 0];
            axis(app.CellMapAxes,'image')
            hold(app.CellMapAxes,'on')
            default_colormap = flipud(brewermap(64, 'rdgy'));
            colormap(app.CellMapAxes, default_colormap);
            
            for ax = app.axesList
                colormap(ax, default_colormap);
                ax.XColor = 'none';
                ax.YColor = 'none';
            end
            app.SpatialFilterAxes.XColor = 'none';
            app.SpatialFilterAxes.YColor = 'none';

            % Start stats timer
            app.resetLabelingTimer();
        end

        % Function to reset the rectangle drawing on cell map
        function resetUISelectionRectangle(app)
            delete(app.rectangleHandle);
            app.rectangleHandle = [];
            app.startPoint = [];
            app.endPoint = [];
            app.currentPoint = [];
            app.rectPos = [];
        end
        
        % Navigates to a specified cell and updates the UI accordingly
        function goCell(app, cell)
            dsetIdx = cell(1);
            cellIdx = cell(2);
            app.selectedCells = [dsetIdx, cellIdx];
            switchDataset(app, dsetIdx);
            switchCell(app);
        end

        function switchCell(app)
            UpdateUI(app);
            if app.tButtonPressed
                % If toggle button is pressed, show the zoomed traces.
                PlotZoomedTraces(app);
            else
                % If not pressed, plot the snapshots.    
                PlotSnapshots(app);
            end
            PlotSnapshotFilters(app);
            PlotTraces(app);
            refreshZoom(app);
        end

        function switchDataset(app, dset)
            % Update dropdown menu value
            app.DatasetDropDown.Value = cellstr(string(dset));
            
            % Update Cell Index limits
            numCells = double(app.session.numCells{dset});
            app.CellIndexEditField.Limits = [1 numCells];
            app.CellSelectionSlider.Limits = [1 numCells];

            % Update Cell Stats Bar Axes Limits
            app.CellStatsAxes.XLim = [0 numCells];

            % Update Main Map Contrast Sliders
            maxProj = double(app.session.maxProj{dset}(:));
            minVal = min(maxProj); maxVal = max(maxProj);
            app.MinContrastSlider.Limits = [minVal maxVal];
            app.MaxContrastSlider.Limits = [minVal maxVal];
            app.MinContrastSlider.Value = minVal;
            app.MaxContrastSlider.Value = maxVal;

            % Update Main Map
            app.CreateMainMapImage(dset);
            app.UpdateCellMap(dset);
            app.UpdateMainMapContrast();
        end
        
        function resetLabelingTimer(app)
            app.LABELING_TIMER = posixtime(datetime);
        end
        
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.axesList = [app.SnapshotAxes1, app.SnapshotAxes2, app.SnapshotAxes3, app.SnapshotAxes4, app.SnapshotAxes5];
            for ax = app.axesList
                axis(ax,'image');
            end
            axis(app.SpatialFilterAxes,'image')
        end

        % Button pushed function: CellButton
        function CellButtonPushed(app, event)
            LABEL = 1;
            app.LabelCells(LABEL);

            [dset, idx] = app.setNextCell();
            app.goCell([dset, idx]);

            % Close the scan-to-select feature after labeling
            if app.ScanToSelectToggle.Value
                app.ScanToSelectToggle.Value = false;
                app.ScanToSelectToggleValueChanged()
            end
        end

        % Button pushed function: NotaCellButton
        function NotaCellButtonPushed(app, event)
            LABEL = -1;
            app.LabelCells(LABEL);

            [dset, idx] = app.setNextCell();
            app.goCell([dset, idx]);

            % Close the scan-to-select feature after labeling
            if app.ScanToSelectToggle.Value
                app.ScanToSelectToggle.Value = false;
                app.ScanToSelectToggleValueChanged()
            end
        end

        % Button pushed function: UnlabeledButton
        function UnlabeledButtonPushed(app, event)
            LABEL = 0;
            app.LabelCells(LABEL);

            [dset, idx] = app.setNextCell();
            app.goCell([dset, idx]);

            % Close the scan-to-select feature after labeling
            if app.ScanToSelectToggle.Value
                app.ScanToSelectToggle.Value = false;
                app.ScanToSelectToggleValueChanged()
            end
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            [dset, idx] = app.setNextCell();
            app.goCell([dset, idx]);
        end

        % Button pushed function: PreviousButton
        function PreviousButtonPushed(app, event)
            [dset, idx] = app.setPreviousCell();
            app.goCell([dset, idx]);
        end

        % Value changed function: CellSelectionSlider
        function CellSelectionSliderValueChanged(app, event)
            idx = round(app.CellSelectionSlider.Value);
            dset = app.getCurrentDataset();
            app.goCell([dset, idx]);
        end

        % Callback function: ExitMenu, UIFigure
        function ExitMenuSelected(app, event)
            app.PlaySnapshotsCheckBox.Value = false;
            stop(app.Timer)
            drawnow;
            delete(app.UIFigure);
        end

        % Window key press function: UIFigure
        function UIFigureWindowKeyPress(app, event)
            if ~isempty(app.session)
                switch event.Key
                    case 'shift'
                        app.ClickToSelectToggle.Value = ~app.ClickToSelectToggle.Value;
                        app.ClickToSelectToggleValueChanged();
                    case 'uparrow'
                        app.CellButtonPushed();
                    case 'downarrow'
                        app.NotaCellButtonPushed();
                    case 'leftarrow'
                        PreviousButtonPushed(app);
                    case 'rightarrow'
                        NextButtonPushed(app);
                    case 'space'
                        app.PlaySnapshotsCheckBox.Value = ~app.PlaySnapshotsCheckBox.Value;
                        PlaySnapshotsCheckBoxValueChanged(app)
                    case 't'
                        if ~app.tButtonPressed
                            app.tButtonPressed = true;
                            PlotZoomedTraces(app);
                        end
                end
            end
        end

        % Window key release function: UIFigure
        function UIFigureWindowKeyRelease(app, event)
            key = event.Key;
             if ~isempty(app.session)
                switch key
                    case 't'
                        if app.tButtonPressed
                            app.tButtonPressed = false;
                            convertSnapshotAxisStyle(app);
                            PlotSnapshots(app);
                        end
                end
            end
        end

        % Value changed function: MaxContrastSlider
        function MaxContrastSliderValueChanged(app, event)
            minSlider = app.MinContrastSlider;
            maxSlider = app.MaxContrastSlider;
            maxSlider.Value = adjust_constrast_value(minSlider, event);

            UpdateMainMapContrast(app);
        end

        % Value changed function: MinContrastSlider
        function MinContrastSliderValueChanged(app, event)
            minSlider = app.MinContrastSlider;
            maxSlider = app.MaxContrastSlider;
            minSlider.Value = adjust_constrast_value(maxSlider, event);

            UpdateMainMapContrast(app);
        end

        % Value changed function: SnapshotMinContrastSlider
        function SnapshotMinContrastSliderValueChanged(app, event)
            minSlider = app.SnapshotMinContrastSlider;
            maxSlider = app.SnapshotMaxContrastSlider;
            minSlider.Value = adjust_constrast_value(maxSlider, event);

            PlotSnapshots(app);
        end

        % Value changed function: SnapshotMaxContrastSlider
        function SnapshotMaxContrastSliderValueChanged(app, event)
            minSlider = app.SnapshotMinContrastSlider;
            maxSlider = app.SnapshotMaxContrastSlider;
            maxSlider.Value = adjust_constrast_value(minSlider, event);

            PlotSnapshots(app);
        end

        % Value changed function: ZoomSlider
        function ZoomSliderValueChanged(app, event)
            zoomLevel = app.ZoomSlider.Value;
            [dset, idx] = getLastSelectedCell(app);
            cellCenter = app.session.cellCenters{dset}(:, idx);
            H = app.session.H{dset}; W = app.session.W{dset};
            [newXLim, newYLim] = calculate_zoom_limits(W, H, cellCenter, zoomLevel);
            updateZoomLimits(app, newXLim, newYLim, zoomLevel);
        end

        % Value changed function: CellIndexEditField
        function CellIndexEditFieldValueChanged(app, event)
            idx = app.CellIndexEditField.Value;
            dset = app.getCurrentDataset();
            app.goCell([dset, idx]);
        end

        % Menu selected function: SaveMenu
        function SaveMenuSelected(app, event)
            % Loop over all precomputed files and save individual label files
            for i = 1:numel(app.precomputedFilePaths)
                labels = createLabelsFile(app, i);
                path = fileparts(app.precomputedFilePaths{i});
                [~, name, ~] = fileparts(app.precomputedFilePaths{i});
                saveFileName = name + "_LABELS.mat";
                saveFilePath = fullfile(path, saveFileName);
        
                % Save the labels
                if isfile(saveFilePath)
                    save(saveFilePath, 'labels', '-append');
                else
                    save(saveFilePath, 'labels');
                end
        
                % Log save
                currentTime = sprintf('[%s] ', datestr(now, 'HH:MM:SS'));
                fprintf('%sQuick saved as: "%s" at %s\n', currentTime, saveFileName, path);
            end
        end

        % Menu selected function: LoadlabelsMenu
        function LoadlabelsMenuSelected(app, event)
            [matched_labels] = open_load_labels_menu(app.precomputedFilePaths);
            figure(app.UIFigure);
        
            for dset = 1:app.session.numDatasets
                try
                    labels_struct = matched_labels{dset};
                    if ~isempty(labels_struct)
                        labels_ex = labels_struct.labels.labels_ex(:);
                        app.session.cellLabels{dset} = labels_ex;
                        app.dataset.labels_ex{dset} = labels_ex;
                    end
                catch
                    figure(app.UIFigure);
                    uialert(app.UIFigure,'Invalid file','Error','Icon', 'error');
                    return
                end
            end
            
            [app.dataset, app.dataset_pretrained, app.method] = update_model(app.dataset, app.dataset_pretrained, app.method);

            [dset, idx] = app.setNextCell();
            app.goCell([dset, idx]);            
        end

        % Value changed function: CellColorTransparencySlider
        function CellColorTransparencySliderValueChanged(app, event)
            dset = app.getCurrentDataset;
            app.UpdateCellMap(dset);
        end

        % Value changed function: PlaySnapshotsCheckBox
        function PlaySnapshotsCheckBoxValueChanged(app, event)
            if app.PlaySnapshotsCheckBox.Value
                playSnapshots(app);
            else
                stopSnapshots(app);
            end
        end

        % Value changed function: CellBorderColorDropDown
        function CellBorderColorDropDownValueChanged(app, event)
            dset = app.getCurrentDataset;
            app.UpdateCellBorders(dset);
            PlotSnapshots(app)
        end

        % Window button down function: UIFigure
        function UIFigureWindowButtonDown(app, event)
            if isempty(app.session)
                return
            end

            % Check if rectangle handle is empty
            isRectangleEmpty = isempty(app.rectangleHandle);
            
            % Condition for multiple selection mode
            isScanning = app.ScanToSelectToggle.Value;
            
            % Condition for zoom mode
            isZoomActive = app.ZoomButton.Value;

            if isRectangleEmpty && xor(isScanning, isZoomActive)
                current_point = app.CellMapAxes.CurrentPoint(1, 1:2);
                XLim = app.CellMapAxes.XLim;
                YLim = app.CellMapAxes.YLim;

                if is_in_screen_limits(current_point, XLim, YLim)
                    app.startPoint = current_point;
                end
            end
        end

        % Window button motion function: UIFigure
        function UIFigureWindowButtonMotion(app, event)
            if isempty(app.session)
                return
            end

            MULTIPLE_SELECTION_COLOR = [0.3, 0.5, 1];
            ZOOM_SELECTION_COLOR = [1, 0.6445, 0];

            % Check if there is a starting point
            isStartPointFull = ~isempty(app.startPoint);

            % Condition for multiple selection mode
            isScanning = app.ScanToSelectToggle.Value;

            % Condition for zoom mode
            isZoomActive = app.ZoomButton.Value;

            if isStartPointFull && xor(isScanning, isZoomActive)
                app.currentPoint = app.CellMapAxes.CurrentPoint(1, 1:2);
                
                app.rectPos = [min(app.startPoint(1), app.currentPoint(1)), ... 
                    min(app.startPoint(2), app.currentPoint(2)), ...
                    abs(app.currentPoint(1) - app.startPoint(1)),... 
                    abs(app.currentPoint(2) - app.startPoint(2))];
                
                delete(app.rectangleHandle);

                if isScanning
                    edgeColor = MULTIPLE_SELECTION_COLOR;
                else
                    edgeColor = ZOOM_SELECTION_COLOR;
                end

                app.rectangleHandle = rectangle('Parent', app.CellMapAxes, 'Position', app.rectPos, 'EdgeColor', edgeColor);
            end
        end

        % Window button up function: UIFigure
        function UIFigureWindowButtonUp(app, event)
            % Check if rectangle handle is empty
            isRectangleFull = ~isempty(app.rectangleHandle);
            
            % Condition for multiple selection mode
            isScanning = app.ScanToSelectToggle.Value;
            
            % Condition for zoom mode
            isZoomActive = app.ZoomButton.Value;
            
            if isRectangleFull && xor(isScanning, isZoomActive)
                finalRectPos = app.rectPos;
                xPoint = finalRectPos(1); yPoint = finalRectPos(2);
                width = finalRectPos(3);  height = finalRectPos(4);
                app.resetUISelectionRectangle();

                dset = app.getCurrentDataset();
                if isZoomActive
                    LIMIT_CONSTANT = 20;                    
                    H = app.session.H{dset}; W = app.session.W{dset};
                    lowLim = min(H/LIMIT_CONSTANT, W/LIMIT_CONSTANT);
                    if width >= lowLim && height >= lowLim
                        handleZoomButton(app, finalRectPos)
                    end
                elseif isScanning
                    selected_cells = find( ...
                        app.session.cellCenters{dset}(1,:) <= xPoint+width ...
                        & app.session.cellCenters{dset}(1,:) >= xPoint ...
                        & app.session.cellCenters{dset}(2,:) <= yPoint+height ...
                        & app.session.cellCenters{dset}(2,:) >= yPoint ...
                        );
                    if app.HideBadCellsCheckBox.Value && app.HideGoodCellsCheckBox.Value
                        selected_cells = selected_cells(app.session.cellLabels{dset}(selected_cells) == 0);
                    elseif app.HideBadCellsCheckBox.Value
                        selected_cells = selected_cells(app.session.cellLabels{dset}(selected_cells) ~= -1);
                    elseif app.HideGoodCellsCheckBox.Value
                        selected_cells = selected_cells(app.session.cellLabels{dset}(selected_cells) ~= 1);
                    end
                
                    if ~isempty(selected_cells)
                        app.selectedCells = [repmat(dset, numel(selected_cells), 1), selected_cells(:)];
                        app.UpdateCellMap(dset);
                    end
                end
                
            end
        end

        % Value changed function: ZoomButton
        function ZoomButtonValueChanged(app, event)
            if app.ScanToSelectToggle.Value && app.ZoomButton.Value
                app.ScanToSelectToggle.Value = false;
                app.ScanToSelectToggleValueChanged();
            end

            if app.ZoomButton.Value
                app.UIFigure.Pointer = 'crosshair';
            else
                app.UIFigure.Pointer = 'arrow';
                app.resetUISelectionRectangle();
            end
        end

        % Button pushed function: ZoomOutButton
        function ZoomOutButtonPushed(app, event)
            dset = app.getCurrentDataset();
            H = app.session.H{dset}; W = app.session.W{dset};
            app.CellMapAxes.XLim = [0 W];
            app.CellMapAxes.YLim = [0 H];
            app.ZoomSlider.Value = 0;
            
            if app.ZoomButton.Value
                app.ZoomButton.Value = false;
                app.ZoomButtonValueChanged();
            end

            if app.ScanToSelectToggle.Value
                app.ScanToSelectToggle.Value = false;
                app.ScanToSelectToggleValueChanged();
            end

            app.ZoomOutButton.Enable = 'off';
        end

        % Value changed function: PlaybackSpeedDropDown
        function PlaybackSpeedDropDownValueChanged(app, event)
            value = str2double(app.PlaybackSpeedDropDown.Value);
            stop(app.Timer)
            app.Timer.Period = value;
            start(app.Timer)
        end

        % Value changed function: HideBadCellsCheckBox
        function HideBadCellsCheckBoxValueChanged(app, event)
            dset = app.getCurrentDataset;
            app.UpdateCellMap(dset);
        end

        % Value changed function: HideGoodCellsCheckBox
        function HideGoodCellsCheckBoxValueChanged(app, event)
            dset = app.getCurrentDataset;
            app.UpdateCellMap(dset);
        end

        % Menu selected function: OpenMenu
        function OpenMenuSelected(app, event)
            dataset_struct           = open_load_datasets_menu();
            precomputedOutputs       = dataset_struct.precomputedOutputs;
            app.dataset_pretrained   = dataset_struct.pretrainedModel;
            app.precomputedFilePaths = dataset_struct.precomputedFilePaths;
            app.balancePretrained    = dataset_struct.balancePretrained;
           
            % Parse inputs and initiate the UI
            ParseInput(app, precomputedOutputs);
            InitiateUIState(app);
        
            % Start at the first dataset, first cell
            STARTING_CELL = [1, 1];
            app.goCell(STARTING_CELL);
            PlaySnapshotsCheckBoxValueChanged(app);
            figure(app.UIFigure);
        end

        % Menu selected function: CreateasortingfileMenu
        function CreateasortingfileMenuSelected(app, event)
            PrecomputeCellCheckGUI;
        end

        % Value changed function: PredictCellsButton
        function PredictCellsButtonValueChanged(app, event)
            predict_cells  = app.PredictCellsButton.Value; 
            app.session = app.session.update_labels(app.dataset, predict_cells);
            dset = app.getCurrentDataset;
            app.UpdateCellMap(dset);
            UpdateUI(app);
        end

        % Value changed function: QueryMethodDropDown
        function QueryMethodDropDownValueChanged(app, event)
            value = app.QueryMethodDropDown.Value;
            weight = app.WeightEditField.Value; 
            if value == "dcal"
                app.WeightEditField.Enable = 'on';
            else
                app.WeightEditField.Enable = 'off';
            end

            if value ~= "none"
                [app.config] = app.config.update_method_name(value, weight);
                [app.method] = app.method.update_method(app.config);
            end
        end

        % Value changed function: WeightEditField
        function WeightEditFieldValueChanged(app, event)
            value = app.WeightEditField.Value;
            app.config.weight = value;
            [app.method] = app.method.update_method(app.config);
        end

        % Menu selected function: SaveclassifierMenu
        function SaveclassifierMenuSelected(app, event)
            dataset = app.dataset; %#ok<*ADPROPLC> 

            [fileName, pathName] = uiputfile('*.mat', 'Save as');
            if isequal(fileName, 0)
                return;
            end

            filepath = fullfile(pathName, fileName);
            save(filepath, 'dataset','-v7.3');
            uialert(app.UIFigure,'Saving was successful.','Success','Icon', 'success');
            figure(app.UIFigure);
        end

        % Value changed function: ScanToSelectToggle
        function ScanToSelectToggleValueChanged(app, event)
            scanToggleValue = app.ScanToSelectToggle.Value;
            clickToggleValue = app.ClickToSelectToggle.Value;

            if clickToggleValue && scanToggleValue
                app.ClickToSelectToggle.Value = false;
            end

            if app.ZoomButton.Value && scanToggleValue
                app.ZoomButton.Value = false; 
                app.ZoomButtonValueChanged();
            end

            if scanToggleValue
                app.UIFigure.Pointer = 'crosshair';
            else
                app.UIFigure.Pointer = 'arrow';
                app.resetUISelectionRectangle();
                [dset, idx] = getLastSelectedCell(app);
                app.goCell([dset, idx]);
            end
        end

        % Value changed function: ClickToSelectToggle
        function ClickToSelectToggleValueChanged(app, event)
            scanToggleValue = app.ScanToSelectToggle.Value;
            clickToggleValue = app.ClickToSelectToggle.Value;

            if clickToggleValue && scanToggleValue
                app.ScanToSelectToggle.Value = false;
            end

            if app.ZoomButton.Value && clickToggleValue
                app.ZoomButton.Value = false; 
                app.ZoomButtonValueChanged();
            end

            if clickToggleValue
                app.UIFigure.Pointer = 'hand';
            else
                app.UIFigure.Pointer = 'arrow';
                idx = app.selectedCells(end);
                dset = app.getCurrentDataset();
                app.goCell([dset, idx]);
            end
        end

        % Value changed function: CellThresholdClassifierEditField
        function ClassifierThresholdEditFieldValueChanged(app, event)
            value = app.CellThresholdClassifierEditField.Value;
            if value < 0 || value > 1
                uialert(app.UIFigure, ...
                    'Threshold must be between 0 and 1.', ...
                    'Invalid Threshold');
                return;
            end
            app.config.cls_threshold = value;
            [app.method] = app.method.update_method(app.config);

            % Update the dataset with new threshold
            [app.dataset, app.dataset_pretrained, app.method] = update_model(app.dataset, app.dataset_pretrained, app.method);
            
            % Update labels according to new threshold
            predict_cells  = app.PredictCellsButton.Value; 
            app.session = app.session.update_labels(app.dataset, predict_cells);
            
            % Refresh screen and UI
            dset = app.getCurrentDataset;
            app.UpdateCellMap(dset);
            app.UpdateUI();
        end

        % Button pushed function: NextDataset
        function NextDatasetButtonPushed(app, event)
            numDatasets = app.session.numDatasets;
            currentDataset = app.DatasetDropDown.Value;
            currentDataset = str2double(currentDataset);
            nextDataset = min(currentDataset + 1, numDatasets);
            app.goCell([nextDataset, 1]);
        end

        % Button pushed function: PreviousDataset
        function PreviousDatasetButtonPushed(app, event)
            currentDataset = app.DatasetDropDown.Value;
            currentDataset = str2double(currentDataset);
            nextDataset = max(currentDataset - 1, 1);
            app.goCell([nextDataset, 1]);
        end

        % Value changed function: DatasetDropDown
        function DatasetDropDownValueChanged(app, event)
            value = app.DatasetDropDown.Value;
            value = str2double(value); 
            app.goCell([value, 1]);
        end

        % Value changed function: SnapshotContrastDropDown
        function SnapshotContrastDropDownValueChanged(app, event)
            value = app.SnapshotContrastDropDown.Value;
            switch value
                case "auto"
                    app.useAutoContrast = true;
        
                case "off"
                    app.useAutoContrast = false;
            end
          
        end

        % Callback function: not associated with a component
        function CellMapAxesButtonDown(app, event)
            
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.Icon = 'ss.png';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @ExitMenuSelected, true);
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @UIFigureWindowButtonDown, true);
            app.UIFigure.WindowButtonUpFcn = createCallbackFcn(app, @UIFigureWindowButtonUp, true);
            app.UIFigure.WindowButtonMotionFcn = createCallbackFcn(app, @UIFigureWindowButtonMotion, true);
            app.UIFigure.WindowKeyPressFcn = createCallbackFcn(app, @UIFigureWindowKeyPress, true);
            app.UIFigure.WindowKeyReleaseFcn = createCallbackFcn(app, @UIFigureWindowKeyRelease, true);
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Tooltip = {'File options'};
            app.Menu.Text = 'Menu';
            app.OpenMenu = uimenu(app.Menu);
            app.OpenMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenMenuSelected, true);
            app.OpenMenu.Tooltip = {'Open a precomputed file to sort'};
            app.OpenMenu.Text = 'Open';
            app.LoadlabelsMenu = uimenu(app.Menu);
            app.LoadlabelsMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadlabelsMenuSelected, true);
            app.LoadlabelsMenu.Tooltip = {'Load previously sorted labels'};
            app.LoadlabelsMenu.Enable = 'off';
            app.LoadlabelsMenu.Text = 'Load labels';
            app.CreateasortingfileMenu = uimenu(app.Menu);
            app.CreateasortingfileMenu.MenuSelectedFcn = createCallbackFcn(app, @CreateasortingfileMenuSelected, true);
            app.CreateasortingfileMenu.Text = 'Create a sorting file';
            app.SaveclassifierMenu = uimenu(app.Menu);
            app.SaveclassifierMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveclassifierMenuSelected, true);
            app.SaveclassifierMenu.Enable = 'off';
            app.SaveclassifierMenu.Text = 'Save classifier';
            app.SaveMenu = uimenu(app.Menu);
            app.SaveMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveMenuSelected, true);
            app.SaveMenu.Tooltip = {'Quick save the sorted file'};
            app.SaveMenu.Enable = 'off';
            app.SaveMenu.Accelerator = 'S';
            app.SaveMenu.Text = 'Save';
            app.ExitMenu = uimenu(app.Menu);
            app.ExitMenu.MenuSelectedFcn = createCallbackFcn(app, @ExitMenuSelected, true);
            app.ExitMenu.Tooltip = {'Exit the app'};
            app.ExitMenu.Text = 'Exit';
            app.MainGridLayout = uigridlayout(app.UIFigure);
            app.CellMapAxes = uiaxes(app.MainGridLayout);
            app.CellMapAxes.XLimitMethod = 'tight';
            app.CellMapAxes.XTick = [];
            app.CellMapAxes.YTick = [];
            app.CellMapAxes.ZTick = [];
            app.TraceAxes = uiaxes(app.MainGridLayout);
            app.SnapshotAxes1 = uiaxes(app.MainGridLayout);
            app.SnapshotAxes1.XLimitMethod = 'tight';
            app.SnapshotAxes1.XTick = [];
            app.SnapshotAxes1.YTick = [];
            app.SnapshotAxes1.ZTick = [];
            app.SnapshotAxes2 = uiaxes(app.MainGridLayout);
            app.SnapshotAxes2.XLimitMethod = 'tight';
            app.SnapshotAxes2.XTick = [];
            app.SnapshotAxes2.YTick = [];
            app.SnapshotAxes2.ZTick = [];
            app.SnapshotAxes3 = uiaxes(app.MainGridLayout);
            app.SnapshotAxes3.XLimitMethod = 'tight';
            app.SnapshotAxes3.XTick = [];
            app.SnapshotAxes3.YTick = [];
            app.SnapshotAxes3.ZTick = [];
            app.SnapshotAxes5 = uiaxes(app.MainGridLayout);
            app.SnapshotAxes5.XLimitMethod = 'tight';
            app.SnapshotAxes5.XTick = [];
            app.SnapshotAxes5.YTick = [];
            app.SnapshotAxes5.ZTick = [];
            app.SnapshotAxes4 = uiaxes(app.MainGridLayout);
            app.SnapshotAxes4.XLimitMethod = 'tight';
            app.SnapshotAxes4.XTick = [];
            app.SnapshotAxes4.YTick = [];
            app.SnapshotAxes4.ZTick = [];
            app.SpatialFilterAxes = uiaxes(app.MainGridLayout);
            app.SpatialFilterAxes.XLimitMethod = 'tight';
            app.SpatialFilterAxes.XTick = [];
            app.SpatialFilterAxes.YTick = [];
            app.SpatialFilterAxes.ZTick = [];
            app.TabGroup = uitabgroup(app.MainGridLayout);
            app.LabelingTab = uitab(app.TabGroup);
            app.LabelingTab.Tooltip = {'Labeling'};
            app.LabelingTab.Title = 'Labeling';
            app.LabelingGridLayout = uigridlayout(app.LabelingTab);
            app.UnlabeledButton = uibutton(app.LabelingGridLayout, 'push');
            app.UnlabeledButton.ButtonPushedFcn = createCallbackFcn(app, @UnlabeledButtonPushed, true);
            app.UnlabeledButton.Enable = 'off';
            app.UnlabeledButton.Text = 'Unlabeled';
            app.NotaCellButton = uibutton(app.LabelingGridLayout, 'push');
            app.NotaCellButton.ButtonPushedFcn = createCallbackFcn(app, @NotaCellButtonPushed, true);
            app.NotaCellButton.Enable = 'off';
            app.NotaCellButton.Text = 'Not a Cell';
            app.CellButton = uibutton(app.LabelingGridLayout, 'push');
            app.CellButton.ButtonPushedFcn = createCallbackFcn(app, @CellButtonPushed, true);
            app.CellButton.Enable = 'off';
            app.CellButton.Text = 'Cell';
            app.PreviousButton = uibutton(app.LabelingGridLayout, 'push');
            app.PreviousButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousButtonPushed, true);
            app.PreviousButton.Enable = 'off';
            app.PreviousButton.Text = '<<Previous';
            app.NextButton = uibutton(app.LabelingGridLayout, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Enable = 'off';
            app.NextButton.Text = 'Next>>';
            app.CellSelectionSlider = uislider(app.LabelingGridLayout);
            app.CellSelectionSlider.Limits = [1 100];
            app.CellSelectionSlider.MajorTicks = [];
            app.CellSelectionSlider.ValueChangedFcn = createCallbackFcn(app, @CellSelectionSliderValueChanged, true);
            app.CellSelectionSlider.MinorTicks = [];
            app.CellSelectionSlider.Enable = 'off';
            app.CellSelectionSlider.Value = 1;
            app.CellIndexEditFieldLabel = uilabel(app.LabelingGridLayout);
            app.CellIndexEditFieldLabel.Enable = 'off';
            app.CellIndexEditFieldLabel.Text = 'Cell Index';
            app.CellIndexEditField = uieditfield(app.LabelingGridLayout, 'numeric');
            app.CellIndexEditField.Limits = [1 Inf];
            app.CellIndexEditField.RoundFractionalValues = 'on';
            app.CellIndexEditField.ValueDisplayFormat = '%.0f';
            app.CellIndexEditField.ValueChangedFcn = createCallbackFcn(app, @CellIndexEditFieldValueChanged, true);
            app.CellIndexEditField.Enable = 'off';
            app.CellIndexEditField.Value = 1;
            app.GoodEditFieldLabel = uilabel(app.LabelingGridLayout);
            app.GoodEditFieldLabel.Enable = 'off';
            app.GoodEditFieldLabel.Text = 'Good';
            app.GoodEditField = uieditfield(app.LabelingGridLayout, 'numeric');
            app.GoodEditField.Limits = [0 Inf];
            app.GoodEditField.RoundFractionalValues = 'on';
            app.GoodEditField.ValueDisplayFormat = '%.0f';
            app.GoodEditField.Editable = 'off';
            app.GoodEditField.Enable = 'off';
            app.GoodEditField.Tooltip = {'Number of good cells'};
            app.BadEditFieldLabel = uilabel(app.LabelingGridLayout);
            app.BadEditFieldLabel.Enable = 'off';
            app.BadEditFieldLabel.Text = 'Bad';
            app.BadEditField = uieditfield(app.LabelingGridLayout, 'numeric');
            app.BadEditField.Limits = [0 Inf];
            app.BadEditField.RoundFractionalValues = 'on';
            app.BadEditField.ValueDisplayFormat = '%.0f';
            app.BadEditField.Editable = 'off';
            app.BadEditField.Enable = 'off';
            app.BadEditField.Tooltip = {'Number of bad cells'};
            app.UnlabeledEditFieldLabel = uilabel(app.LabelingGridLayout);
            app.UnlabeledEditFieldLabel.Enable = 'off';
            app.UnlabeledEditFieldLabel.Text = 'Unlabeled';
            app.UnlabeledEditField = uieditfield(app.LabelingGridLayout, 'numeric');
            app.UnlabeledEditField.Limits = [0 Inf];
            app.UnlabeledEditField.RoundFractionalValues = 'on';
            app.UnlabeledEditField.ValueDisplayFormat = '%.0f';
            app.UnlabeledEditField.Editable = 'off';
            app.UnlabeledEditField.Enable = 'off';
            app.UnlabeledEditField.Tooltip = {'Number of unlabeled cells'};
            app.LabeledEditFieldLabel = uilabel(app.LabelingGridLayout);
            app.LabeledEditFieldLabel.Enable = 'off';
            app.LabeledEditFieldLabel.Text = 'Labeled';
            app.LabeledEditField = uieditfield(app.LabelingGridLayout, 'numeric');
            app.LabeledEditField.Limits = [0 Inf];
            app.LabeledEditField.RoundFractionalValues = 'on';
            app.LabeledEditField.ValueDisplayFormat = '%.0f';
            app.LabeledEditField.Editable = 'off';
            app.LabeledEditField.Enable = 'off';
            app.LabeledEditField.Tooltip = {'Number of unlabeled cells'};
            app.NextDataset = uibutton(app.LabelingGridLayout, 'push');
            app.NextDataset.ButtonPushedFcn = createCallbackFcn(app, @NextDatasetButtonPushed, true);
            app.NextDataset.Enable = 'off';
            app.NextDataset.Text = '>>';
            app.PreviousDataset = uibutton(app.LabelingGridLayout, 'push');
            app.PreviousDataset.ButtonPushedFcn = createCallbackFcn(app, @PreviousDatasetButtonPushed, true);
            app.PreviousDataset.Enable = 'off';
            app.PreviousDataset.Text = '<<';
            app.DatasetDropDown = uidropdown(app.LabelingGridLayout);
            app.DatasetDropDown.Items = {'1'};
            app.DatasetDropDown.ValueChangedFcn = createCallbackFcn(app, @DatasetDropDownValueChanged, true);
            app.DatasetDropDown.Enable = 'off';
            app.DatasetDropDown.Value = '1';
            app.GoodEditField_2Label = uilabel(app.LabelingGridLayout);
            app.GoodEditField_2Label.Enable = 'off';
            app.GoodEditField_2Label.Text = 'Good';
            app.TotalGoodEditField = uieditfield(app.LabelingGridLayout, 'numeric');
            app.TotalGoodEditField.Editable = 'off';
            app.TotalGoodEditField.Enable = 'off';
            app.BadEditField_2Label = uilabel(app.LabelingGridLayout);
            app.BadEditField_2Label.Enable = 'off';
            app.BadEditField_2Label.Text = 'Bad';
            app.TotalBadEditField = uieditfield(app.LabelingGridLayout, 'numeric');
            app.TotalBadEditField.Editable = 'off';
            app.TotalBadEditField.Enable = 'off';
            app.UnlabeledEditField_2Label = uilabel(app.LabelingGridLayout);
            app.UnlabeledEditField_2Label.Enable = 'off';
            app.UnlabeledEditField_2Label.Text = 'Unlabeled';
            app.TotalUnlabeledEditField = uieditfield(app.LabelingGridLayout, 'numeric');
            app.TotalUnlabeledEditField.Editable = 'off';
            app.TotalUnlabeledEditField.Enable = 'off';
            app.DatasetSummaryLabel = uilabel(app.LabelingGridLayout);
            app.DatasetSummaryLabel.Enable = 'off';
            app.DatasetSummaryLabel.Tooltip = {'Change main map contrast'};
            app.DatasetSummaryLabel.Text = 'Dataset Summary';
            app.TotalSummaryLabel = uilabel(app.LabelingGridLayout);
            app.TotalSummaryLabel.Enable = 'off';
            app.TotalSummaryLabel.Tooltip = {'Change main map contrast'};
            app.TotalSummaryLabel.Text = 'Total Summary';
            app.TotalProgressLabeledLabel = uilabel(app.LabelingGridLayout);
            app.TotalProgressLabeledLabel.Enable = 'off';
            app.TotalProgressLabeledLabel.Text = 'Total Labeled: 0/0 (0%)';
            app.ToolsTab = uitab(app.TabGroup);
            app.ToolsTab.Tooltip = {'Advanced options and tools'};
            app.ToolsTab.Title = 'Tools';
            app.ToolsGridLayout = uigridlayout(app.ToolsTab);
            app.MinContrastSlider = uislider(app.ToolsGridLayout);
            app.MinContrastSlider.Limits = [0 10000];
            app.MinContrastSlider.MajorTicks = [];
            app.MinContrastSlider.ValueChangedFcn = createCallbackFcn(app, @MinContrastSliderValueChanged, true);
            app.MinContrastSlider.MinorTicks = [];
            app.MinContrastSlider.Enable = 'off';
            app.MinContrastSlider.Tooltip = {'Change main map contrast'};
            app.MainMapContrastLabel = uilabel(app.ToolsGridLayout);
            app.MainMapContrastLabel.Enable = 'off';
            app.MainMapContrastLabel.Tooltip = {'Change main map contrast'};
            app.MainMapContrastLabel.Text = 'Main Map Contrast';
            app.MaxContrastSlider = uislider(app.ToolsGridLayout);
            app.MaxContrastSlider.Limits = [0 10000];
            app.MaxContrastSlider.MajorTicks = [];
            app.MaxContrastSlider.ValueChangedFcn = createCallbackFcn(app, @MaxContrastSliderValueChanged, true);
            app.MaxContrastSlider.MinorTicks = [];
            app.MaxContrastSlider.Enable = 'off';
            app.MaxContrastSlider.Tooltip = {'Change main map contrast'};
            app.MaxContrastSlider.Value = 5000;
            app.HideBadCellsCheckBox = uicheckbox(app.ToolsGridLayout);
            app.HideBadCellsCheckBox.ValueChangedFcn = createCallbackFcn(app, @HideBadCellsCheckBoxValueChanged, true);
            app.HideBadCellsCheckBox.Enable = 'off';
            app.HideBadCellsCheckBox.Text = '';
            app.PlaySnapshotsCheckBox = uicheckbox(app.ToolsGridLayout);
            app.PlaySnapshotsCheckBox.ValueChangedFcn = createCallbackFcn(app, @PlaySnapshotsCheckBoxValueChanged, true);
            app.PlaySnapshotsCheckBox.Enable = 'off';
            app.PlaySnapshotsCheckBox.Text = '';
            app.SnapshotMinContrastSlider = uislider(app.ToolsGridLayout);
            app.SnapshotMinContrastSlider.Limits = [0 10000];
            app.SnapshotMinContrastSlider.MajorTicks = [];
            app.SnapshotMinContrastSlider.ValueChangedFcn = createCallbackFcn(app, @SnapshotMinContrastSliderValueChanged, true);
            app.SnapshotMinContrastSlider.MinorTicks = [];
            app.SnapshotMinContrastSlider.Enable = 'off';
            app.SnapshotMinContrastSlider.Tooltip = {'Change snapshot movie contrast'};
            app.SnapshotMaxContrastSlider = uislider(app.ToolsGridLayout);
            app.SnapshotMaxContrastSlider.Limits = [0 10000];
            app.SnapshotMaxContrastSlider.MajorTicks = [];
            app.SnapshotMaxContrastSlider.ValueChangedFcn = createCallbackFcn(app, @SnapshotMaxContrastSliderValueChanged, true);
            app.SnapshotMaxContrastSlider.MinorTicks = [];
            app.SnapshotMaxContrastSlider.Enable = 'off';
            app.SnapshotMaxContrastSlider.Tooltip = {'Change snapshot movie contrast'};
            app.SnapshotMaxContrastSlider.Value = 5000;
            app.ZoomButton = uibutton(app.ToolsGridLayout, 'state');
            app.ZoomButton.ValueChangedFcn = createCallbackFcn(app, @ZoomButtonValueChanged, true);
            app.ZoomButton.Enable = 'off';
            app.ZoomButton.Tooltip = {'Scan to zoom'};
            app.ZoomButton.Icon = 'icon-mat-zoom-in.png';
            app.ZoomButton.Text = '';
            app.ZoomOutButton = uibutton(app.ToolsGridLayout, 'push');
            app.ZoomOutButton.ButtonPushedFcn = createCallbackFcn(app, @ZoomOutButtonPushed, true);
            app.ZoomOutButton.Icon = 'icon-mat-zoom-out.png';
            app.ZoomOutButton.Enable = 'off';
            app.ZoomOutButton.Tooltip = {'Reset zoom'};
            app.ZoomOutButton.Text = '';
            app.ZoomSlider = uislider(app.ToolsGridLayout);
            app.ZoomSlider.MajorTicks = [];
            app.ZoomSlider.ValueChangedFcn = createCallbackFcn(app, @ZoomSliderValueChanged, true);
            app.ZoomSlider.MinorTicks = [];
            app.ZoomSlider.Enable = 'off';
            app.ZoomSlider.Tooltip = {'Zoom on current cell'};
            app.ZoomLabel = uilabel(app.ToolsGridLayout);
            app.ZoomLabel.Enable = 'off';
            app.ZoomLabel.Tooltip = {'Zoom on current cell'};
            app.ZoomLabel.Text = 'Zoom';
            app.CellColorTransparencySlider = uislider(app.ToolsGridLayout);
            app.CellColorTransparencySlider.Limits = [0 1];
            app.CellColorTransparencySlider.MajorTicks = [];
            app.CellColorTransparencySlider.ValueChangedFcn = createCallbackFcn(app, @CellColorTransparencySliderValueChanged, true);
            app.CellColorTransparencySlider.MinorTicks = [];
            app.CellColorTransparencySlider.Enable = 'off';
            app.CellColorTransparencySlider.Tooltip = {'Change cell color transparency'};
            app.CellColorTransparencySlider.Value = 0.5;
            app.CellLabelTransparencySliderLabel = uilabel(app.ToolsGridLayout);
            app.CellLabelTransparencySliderLabel.Enable = 'off';
            app.CellLabelTransparencySliderLabel.Text = 'Cell Color Transparency';
            app.CellBorderColorLabel = uilabel(app.ToolsGridLayout);
            app.CellBorderColorLabel.Enable = 'off';
            app.CellBorderColorLabel.Text = 'Cell Border Color:';
            app.CellBorderColorDropDown = uidropdown(app.ToolsGridLayout);
            app.CellBorderColorDropDown.Items = {'Black', 'White', 'Blue', 'Light Blue', 'Orange', 'Barbie', 'Purple', 'NO BORDER'};
            app.CellBorderColorDropDown.ItemsData = {'black', 'white', '[0.30,0.75,0.93]', '[0.7, 0.8, 0.85]', '[1 0.5469 0]', '[1,0.4102,0.7031]', '[0.5 0 0.5]', 'noborder'};
            app.CellBorderColorDropDown.ValueChangedFcn = createCallbackFcn(app, @CellBorderColorDropDownValueChanged, true);
            app.CellBorderColorDropDown.Enable = 'off';
            app.CellBorderColorDropDown.Value = '[0.7, 0.8, 0.85]';
            app.PlaybackSpeedLabel = uilabel(app.ToolsGridLayout);
            app.PlaybackSpeedLabel.Enable = 'off';
            app.PlaybackSpeedLabel.Text = 'Playback Speed:';
            app.PlaybackSpeedDropDown = uidropdown(app.ToolsGridLayout);
            app.PlaybackSpeedDropDown.Items = {'Slow', 'Moderate', 'Fast'};
            app.PlaybackSpeedDropDown.ItemsData = {'0.2', '0.15', '0.1'};
            app.PlaybackSpeedDropDown.ValueChangedFcn = createCallbackFcn(app, @PlaybackSpeedDropDownValueChanged, true);
            app.PlaybackSpeedDropDown.Enable = 'off';
            app.PlaybackSpeedDropDown.Value = '0.15';
            app.HideBadCellsLabel = uilabel(app.ToolsGridLayout);
            app.HideBadCellsLabel.Enable = 'off';
            app.HideBadCellsLabel.Text = 'Hide Bad Cells';
            app.PlaySnapshotsLabel = uilabel(app.ToolsGridLayout);
            app.PlaySnapshotsLabel.Enable = 'off';
            app.PlaySnapshotsLabel.Text = 'Play Snapshots';
            app.HideGoodCellsLabel = uilabel(app.ToolsGridLayout);
            app.HideGoodCellsLabel.Enable = 'off';
            app.HideGoodCellsLabel.Text = 'Hide Good Cells';
            app.HideGoodCellsCheckBox = uicheckbox(app.ToolsGridLayout);
            app.HideGoodCellsCheckBox.ValueChangedFcn = createCallbackFcn(app, @HideGoodCellsCheckBoxValueChanged, true);
            app.HideGoodCellsCheckBox.Enable = 'off';
            app.HideGoodCellsCheckBox.Text = '';
            app.ClickToSelectToggle = uibutton(app.ToolsGridLayout, 'state');
            app.ClickToSelectToggle.ValueChangedFcn = createCallbackFcn(app, @ClickToSelectToggleValueChanged, true);
            app.ClickToSelectToggle.Enable = 'off';
            app.ClickToSelectToggle.Icon = 'icon-fa-hand-pointer-o.png';
            app.ClickToSelectToggle.Text = '';
            app.ScanToSelectToggle = uibutton(app.ToolsGridLayout, 'state');
            app.ScanToSelectToggle.ValueChangedFcn = createCallbackFcn(app, @ScanToSelectToggleValueChanged, true);
            app.ScanToSelectToggle.Enable = 'off';
            app.ScanToSelectToggle.Icon = 'icon-mat-select-all.png';
            app.ScanToSelectToggle.Text = '';
            app.SnapshotContrastDropDownLabel = uilabel(app.ToolsGridLayout);
            app.SnapshotContrastDropDownLabel.Text = 'Snapshot Contrast:';
            app.SnapshotContrastDropDown = uidropdown(app.ToolsGridLayout);
            app.SnapshotContrastDropDown.Items = {'auto', 'off'};
            app.SnapshotContrastDropDown.ValueChangedFcn = createCallbackFcn(app, @SnapshotContrastDropDownValueChanged, true);
            app.SnapshotContrastDropDown.Value = 'auto';
            app.ActiveLearningTab = uitab(app.TabGroup);
            app.ActiveLearningTab.Tooltip = {'Active Learning control tab'};
            app.ActiveLearningTab.Title = 'Active Learning';
            app.ActiveLearningGridLayout = uigridlayout(app.ActiveLearningTab);
            app.CellStatsAxes = uiaxes(app.ActiveLearningGridLayout);
            app.CellStatsAxes.XTick = [];
            app.CellStatsAxes.YTick = [];
            app.CellStatsAxes.ZTick = [];
            app.PredictCellsButton = uibutton(app.ActiveLearningGridLayout, 'state');
            app.PredictCellsButton.ValueChangedFcn = createCallbackFcn(app, @PredictCellsButtonValueChanged, true);
            app.PredictCellsButton.Enable = 'off';
            app.PredictCellsButton.Text = 'Predict Cells';
            app.ActSortScoreLabel = uilabel(app.ActiveLearningGridLayout);
            app.ActSortScoreLabel.Enable = 'off';
            app.ActSortScoreLabel.Text = 'ActSort Score:';
            app.ActSortScore = uilabel(app.ActiveLearningGridLayout);
            app.ActSortScore.Enable = 'off';
            app.ActSortScore.Text = {'-'; ''};
            app.ActSortDecisionLabel = uilabel(app.ActiveLearningGridLayout);
            app.ActSortDecisionLabel.Enable = 'off';
            app.ActSortDecisionLabel.Text = 'ActSort Decision:';
            app.ActSortDecision = uilabel(app.ActiveLearningGridLayout);
            app.ActSortDecision.Enable = 'off';
            app.ActSortDecision.Text = {'-'; ''};
            app.UserDecisionLabel = uilabel(app.ActiveLearningGridLayout);
            app.UserDecisionLabel.Enable = 'off';
            app.UserDecisionLabel.Text = 'User Decision:';
            app.UserDecision = uilabel(app.ActiveLearningGridLayout);
            app.UserDecision.Enable = 'off';
            app.UserDecision.Text = {'-'; ''};
            app.OverallLabel = uilabel(app.ActiveLearningGridLayout);
            app.OverallLabel.Enable = 'off';
            app.OverallLabel.Text = 'Overall:';
            app.ActSortLabel = uilabel(app.ActiveLearningGridLayout);
            app.ActSortLabel.Enable = 'off';
            app.ActSortLabel.Text = 'ActSort:';
            app.UserLabel = uilabel(app.ActiveLearningGridLayout);
            app.UserLabel.Enable = 'off';
            app.UserLabel.Text = 'User:';
            app.QueryMethodLabel = uilabel(app.ActiveLearningGridLayout);
            app.QueryMethodLabel.Enable = 'off';
            app.QueryMethodLabel.Text = 'Query Method';
            app.QueryMethodDropDown = uidropdown(app.ActiveLearningGridLayout);
            app.QueryMethodDropDown.Items = {'DCAL', 'CAL', 'DAL', 'Random', 'Linear', 'None'};
            app.QueryMethodDropDown.ItemsData = {'dcal', 'cal', 'dal', 'random', 'algo-rank', 'none'};
            app.QueryMethodDropDown.ValueChangedFcn = createCallbackFcn(app, @QueryMethodDropDownValueChanged, true);
            app.QueryMethodDropDown.Enable = 'off';
            app.QueryMethodDropDown.Value = 'dcal';
            app.WeightEditField = uieditfield(app.ActiveLearningGridLayout, 'numeric');
            app.WeightEditField.Limits = [0 1];
            app.WeightEditField.ValueChangedFcn = createCallbackFcn(app, @WeightEditFieldValueChanged, true);
            app.WeightEditField.Enable = 'off';
            app.WeightEditField.Value = 0.5;
            app.CellThresholdClassifierEditFieldLabel = uilabel(app.ActiveLearningGridLayout);
            app.CellThresholdClassifierEditFieldLabel.Enable = 'off';
            app.CellThresholdClassifierEditFieldLabel.Text = 'Cell Threshold Classifier';
            app.CellThresholdClassifierEditField = uieditfield(app.ActiveLearningGridLayout, 'numeric');
            app.CellThresholdClassifierEditField.Limits = [0 1];
            app.CellThresholdClassifierEditField.ValueChangedFcn = createCallbackFcn(app, @ClassifierThresholdEditFieldValueChanged, true);
            app.CellThresholdClassifierEditField.Enable = 'off';
            app.CellThresholdClassifierEditField.Value = 0.65;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CellSorter

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end